classdef Camima < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CamimaMain           matlab.ui.Figure
        MainTabGroup         matlab.ui.container.TabGroup
        HOMETab              matlab.ui.container.Tab
        CameraPanel          matlab.ui.container.Panel
        CameraBrowserButton  matlab.ui.control.Button
        CameraSettingButton  matlab.ui.control.Button
        ImastPanel           matlab.ui.container.Panel
        Tab2                 matlab.ui.container.Tab
        Tab3                 matlab.ui.container.Tab
        Status               matlab.ui.control.EditField
    end


    properties (Access = public)
        cameraTree % Description
    end

    methods (Access = public)
    
        function ResetAllCameras(app)
            app.Status.Value = 'Reseting all cameras...';
            imaqreset; %reset all cameras from image acquisition tool box
            %clear app.cameraTree
            app.cameraTree = [];
            %if success status bar will get feedback
            app.Status.Value = 'All cameras has been reset!';           
        end
        
        function cameraBrowser(app,position)
            %new GUI for camera browser
            browser = figure('MenuBar','none','Name','Camera Browser','NumberTitle','off',...
                'ToolBar','none','Position',[position(1) position(2)-500 250 500]);
            
            %add toolbar for push button
            toolbar = uitoolbar(browser);
            
            % save main handle app in UserData
            browser.UserData = app;
            
            % add series button for: reset, refresh and setting cameras
            uipushtool('Parent',toolbar,'TooltipString','Reset all cameras','ClickedCallback',...
                @app.resetAndRefreshCameraBrowser);
            uipushtool('Parent',toolbar,'TooltipString','Refresh all cameras','ClickedCallback',...
                @app.refreshCameraBrowser);
            
            %refresh cameraTree in browser
            refreshCameraBrowser(app,browser)
            
        end
        
        function SearchAndGetCameras(app)
            %search for cameras
            inputs = imaqhwinfo;
            %List Adaptors Available
            adaptorsLogged = inputs.InstalledAdaptors;
            %count Adaptors number
            adaptorLoggedNumber = length(adaptorsLogged);
            %save adaptors in app.cameraTree
            app.cameraTree.adaptor.Name = adaptorsLogged;
            app.cameraTree.adaptor.Number = adaptorLoggedNumber;
            app.cameraTree.basicinfo.adaptorNumber = adaptorLoggedNumber;
            
            %initialize cameraNumber for Basicinfo
            app.cameraTree.basicinfo.cameraNumber = 0;
            
            if adaptorLoggedNumber ~= 0
                %Test all available cameras
                for i = 1:adaptorLoggedNumber
                    %get info for all cameras
                    adaptorName = adaptorsLogged{i};
                    adaptorinfo = imaqhwinfo(adaptorName);
                    cameraIDs = adaptorinfo.DeviceIDs;
                    cameraNumber = length(cameraIDs);
                    %renew total cameraNumber in basicinfo
                    app.cameraTree.basicinfo.cameraNumber = app.cameraTree.basicinfo.cameraNumber +cameraNumber;
                    
                    %record available camera for this adaptor
                    app.cameraTree.(adaptorName).ID = cameraIDs;
                    app.cameraTree.(adaptorName).Number = cameraNumber;
                    
                    if cameraNumber ~= 0
                        % Test all Format for camera
                        for j = 1:cameraNumber
                            camerainfo = imaqhwinfo(adaptorName,cameraIDs{j});
                            app.cameraTree.([adaptorName '_' num2str(cameraIDs{j})]) = camerainfo;
                        end
                    end
                end
                
                %tell users status of all camera
                app.Status.Value = ['Search End: ' num2str(app.cameraTree.adaptor.Number) ' adaptors available;  ' ...
                    num2str(app.cameraTree.basicinfo.cameraNumber) ' cameras available.'];
            else
                app.Status.Value = 'No Adaptors available, please install them first!';
            end
        end

        function refreshCameraBrowser(app,~,~)
            %get tree-node package
            import uiextras.jTree.*;
                        
            %for sub-figure callback
            f = gcf;
            t = Tree('Parent',f);
                        
            adaptorName = app.cameraTree.adaptor.Name;
            adaptorNumber = app.cameraTree.adaptor.Number;
            for i = 1:adaptorNumber
                % build adaptor node
                adaptornode = TreeNode('Name',adaptorName{i},'Parent',t.Root);
                
                %get cameras for this adaptor
                cameraID =  app.cameraTree.(adaptorName{i}).ID;
                cameraNumber = app.cameraTree.(adaptorName{i}).Number;
                for j = 1:cameraNumber
                    cameranode = TreeNode('Name',[adaptorName{i} '_' num2str(cameraID{j})],'Parent',adaptornode);
                    
                    %get all format for this camera
                    camerainfo = app.cameraTree.([adaptorName{i} '_' num2str(cameraID{j})]);
                    supportedformat = camerainfo.SupportedFormats;
                    supportedformatNumber = length(supportedformat);
                    for k = 1:supportedformatNumber
                        TreeNode('Name',supportedformat{k},'Parent',cameranode);
                    end
                    %expand cameranode
                    cameranode.expand();
                end
                %expand adaptors
                adaptornode.expand();
            end
            %expand all
            t.Root.expand();
        end
        
        function resetAndRefreshCameraBrowser(app,~,~)
            ResetAllCameras(app);
            SearchAndGetCameras(app);
            refreshCameraBrowser(app);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %reset all cameras
            app.ResetAllCameras();
            %Searcha and get all available cameras, put in app.cameraTree
            app.SearchAndGetCameras();
        end

        % Size changed function: CamimaMain
        function CamimaMainSizeChanged(app, event)
           % h = gcbo;
           % position = h.Position;
            position = app.CamimaMain.Position;
            app.Status.Position = [0 0 position(3) 22];
            app.MainTabGroup.Position = [0 22 position(3) position(4)-22];
        end

        % Size changed function: HOMETab
        function HOMETabSizeChanged(app, event)
            position = app.HOMETab.Position;
            if ~isempty(app.CameraPanel)
                app.CameraPanel.Position = [0 0 position(3)/5 position(4)];
            end
            if ~isempty(app.ImastPanel)
                app.ImastPanel.Position = [position(3)/5 0 position(3)/5 position(4)];
            end
        end

        % Size changed function: CameraPanel
        function CameraPanelSizeChanged(app, event)
            position = app.CameraPanel.Position;
            if ~isempty(app.CameraBrowserButton)
                app.CameraBrowserButton.Position = [5 5 (position(3)-15)/2 position(4)-30];
            end
            if ~isempty(app.CameraSettingButton)
                app.CameraSettingButton.Position = [(position(3)-15)/2+10 5 (position(3)-15)/2 position(4)-30];
            end
        end

        % Button pushed function: CameraBrowserButton
        function CameraBrowserButtonPushed(app, event)
            position = app.CamimaMain.Position;
            cameraBrowser(app,position);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CamimaMain = uifigure;
            app.CamimaMain.Name = 'Camima';
            app.CamimaMain.SizeChangedFcn = createCallbackFcn(app, @CamimaMainSizeChanged, true);
            setAutoResize(app, app.CamimaMain, true)
            app.MainTabGroup = uitabgroup(app.CamimaMain);
            app.HOMETab = uitab(app.MainTabGroup);
            app.HOMETab.SizeChangedFcn = createCallbackFcn(app, @HOMETabSizeChanged, true);
            app.HOMETab.Title = 'HOME';
            app.HOMETab.Units = 'pixels';
            app.CameraPanel = uipanel(app.HOMETab);
            app.CameraPanel.Title = 'Camera';
            app.CameraPanel.SizeChangedFcn = createCallbackFcn(app, @CameraPanelSizeChanged, true);
            app.CameraBrowserButton = uibutton(app.CameraPanel, 'push');
            app.CameraBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @CameraBrowserButtonPushed, true);
            app.CameraBrowserButton.Icon = 'cameraIcon.png';
            app.CameraBrowserButton.Text = {'Camera'; 'Browser'};
            app.CameraSettingButton = uibutton(app.CameraPanel, 'push');
            app.CameraSettingButton.Icon = 'setting.png';
            app.CameraSettingButton.Text = {'Camera'; 'Setting'};
            app.ImastPanel = uipanel(app.HOMETab);
            app.ImastPanel.Title = 'Image Stream';
            app.Tab2 = uitab(app.MainTabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab2.Units = 'pixels';
            app.Tab3 = uitab(app.MainTabGroup);
            app.Tab3.Title = 'Tab3';
            app.Tab3.Units = 'pixels';
            app.Status = uieditfield(app.CamimaMain, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Camima()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CamimaMain)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CamimaMain)
        end
    end
end