classdef Camset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CamsetMain                      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        MainTabGroup                    matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        SearchCameraPanel               matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        InitializeButton                matlab.ui.control.Button
        CameraBrowserButton             matlab.ui.control.Button
        ChooseCameraPanel               matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        SetButton                       matlab.ui.control.StateButton
        ConnectingLamp                  matlab.ui.control.Lamp
        CameraDropDown                  matlab.ui.control.DropDown
        CameraDropDownLabel             matlab.ui.control.Label
        ControlPanel                    matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        DefaultButton                   matlab.ui.control.Button
        LoadSettingButton               matlab.ui.control.Button
        SaveSettingButton               matlab.ui.control.Button
        MoreTab                         matlab.ui.container.Tab
        Status                          matlab.ui.control.EditField
        GridLayout2                     matlab.ui.container.GridLayout
        StartPreviewButton              matlab.ui.control.Button
        StopPreviewButton               matlab.ui.control.Button
        GridLayout3                     matlab.ui.container.GridLayout
        StartButton                     matlab.ui.control.Button
        TriggerButton                   matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        GridLayout4                     matlab.ui.container.GridLayout
        GeneralSettingButton            matlab.ui.control.Button
        GridLayout5                     matlab.ui.container.GridLayout
        DefaulROItButton                matlab.ui.control.Button
        ROIButton                       matlab.ui.control.Button
        XcenterSpinner                  matlab.ui.control.Spinner
        XcenterSpinnerPCOLabel          matlab.ui.control.Label
        YcenterSpinner                  matlab.ui.control.Spinner
        YcenterSpinnerPCOLabel          matlab.ui.control.Label
        WidthSpinner                    matlab.ui.control.Spinner
        WidthSpinnerPCOLabel            matlab.ui.control.Label
        HeightSpinner                   matlab.ui.control.Spinner
        HeightSpinnerPCOLabel           matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        TriggerTypeDropDown             matlab.ui.control.DropDown
        TriggerTypeDropDownPCOLabel     matlab.ui.control.Label
        TriggerConditionDropDown        matlab.ui.control.DropDown
        ConditionLabel                  matlab.ui.control.Label
        TriggerSourceDropDown           matlab.ui.control.DropDown
        SourceDropDownLabel             matlab.ui.control.Label
        NumberoftriggerSpinner          matlab.ui.control.Spinner
        NumberoftriggerSpinnerPCOLabel  matlab.ui.control.Label
        FrameperTriggerSpinner          matlab.ui.control.Spinner
        FrameperTriggerSpinnerPCOLabel  matlab.ui.control.Label
        ImageShow                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        subuiTree % subui
        camima % main app handle
        vid % all available camera handle
        cameraTree %camera info and handles of all cameras
    end

    methods (Access = public)
    
        function EnableSetting(app)
            %Manu Bar
            %app.CameraBrowserButton.Enable = 'off';
            app.CameraDropDown.Enable = 'off';
            app.DefaultButton.Enable = 'on';
            app.LoadSettingButton.Enable = 'on';
            app.SaveSettingButton.Enable = 'on';
            %app.InitializeButton.Enable = 'on';
            
            %Trigger
            app.TriggerTypeDropDown.Enable = 'on';
            app.TriggerSourceDropDown.Enable = 'on';
            app.TriggerConditionDropDown.Enable = 'on';
            app.FrameperTriggerSpinner.Enable = 'on';
            app.NumberoftriggerSpinner.Enable = 'on';
            
            %ROI
            app.XcenterSpinner.Enable = 'on';
            app.WidthSpinner.Enable = 'on';
            app.YcenterSpinner.Enable = 'on';
            app.HeightSpinner.Enable = 'on';
            app.ROIButton.Enable = 'on';
            app.DefaulROItButton.Enable = 'on';
            
            %Preview panel
            app.StartPreviewButton.Enable = 'on';
            app.StopPreviewButton.Enable = 'off';
            
            %get cameraName
            cameraName = app.CameraDropDown.Value;
            %get current vid
            curvid = app.vid.(cameraName);
            
            %AcquirePanel     
            if strcmp(curvid.Running,'on')
                app.StartButton.Enable = 'off';
                app.StopButton.Enable = 'on';
                app.TriggerButton.Enable = 'on';
                app.ExportButton.Enable = 'off';
            else
                app.StartButton.Enable = 'on';
                app.StopButton.Enable = 'off';
                app.TriggerButton.Enable = 'off';
                app.ExportButton.Enable = 'on';
            end
            
            %CameraSetting Panel
            app.GeneralSettingButton.Enable = 'on';
        end
        
        function refreshSetting(app)
            %get cameraName
            cameraName = app.CameraDropDown.Value;
            %get current vid
            curvid = app.vid.(cameraName);
            %Trigger
            app.FrameperTriggerSpinner.Value = curvid.FramesPerTrigger;
            app.TriggerTypeDropDown.Value = curvid.TriggerType;
            app.NumberoftriggerSpinner.Value = curvid.TriggerRepeat + 1; % TRIGGERNUMBER actually is setting vid.TRIGGERREPEAT +1
            
            %get all possible trigger settting
            triggerSets = triggerinfo(curvid,curvid.TriggerType);
            triggerSets_array = squeeze(struct2cell(triggerSets));
            app.TriggerConditionDropDown.Items = unique(triggerSets_array(2,:));
            app.TriggerSourceDropDown.Items = unique(triggerSets_array(3,:));
            
            app.TriggerSourceDropDown.Value = curvid.TriggerSource;
            app.TriggerConditionDropDown.Value = curvid.TriggerCondition;
            
            SPcameraName = split(cameraName,'_');
            adaptorName = SPcameraName(1);
            if strcmp(adaptorName, 'pcocameraadaptor')
                %special for PCO
                userdata = curvid.UserData;
                ROIposition = userdata.ROIposition;
                app.XcenterSpinner.Value = ROIposition(1)+ROIposition(3)/2;
                app.WidthSpinner.Value = ROIposition(3);
                app.YcenterSpinner.Value = ROIposition(2)+ROIposition(4)/2;
                app.HeightSpinner.Value = ROIposition(4);
            else
                %ROI (soft; in-line in IMAQ)
                ROIposition = curvid.ROIposition;
                app.XcenterSpinner.Value = ROIposition(1)+ROIposition(3)/2;
                app.WidthSpinner.Value = ROIposition(3);
                app.YcenterSpinner.Value = ROIposition(2)+ROIposition(4)/2;
                app.HeightSpinner.Value = ROIposition(4);
            end
        end
        
        function cameraBrowser(app,position)
            
            if ~isempty(app.subuiTree) && ~isempty(app.subuiTree.cameraBrowser) && app.subuiTree.cameraBrowser.Count == 1 && ishandle(app.subuiTree.cameraBrowser.obj)
                browser = app.subuiTree.cameraBrowser.obj;
                browser.Position = [position(1) position(2)-600 250 500];
                browser.Visible = 'on';
            else
                %new GUI for camera browser
                browser = figure('MenuBar','none','Name','Camera Browser','NumberTitle','off',...
                    'ToolBar','none','Position',[position(1) position(2)-600 250 500],'CloseRequestFcn',@app.cameraBrowserClose);
                
                %add toolbar for push button
                toolbar = uitoolbar(browser);
                
                % save main handle app in UserData
                browser.UserData = app;
                
                % add series button for: reset, refresh and setting cameras
                
                % Load the Redo icon
  
                uipushtool('Parent',toolbar,'TooltipString','Reset all cameras','ClickedCallback',...
                    @app.resetAndRefreshCameraBrowser);
                uipushtool('Parent',toolbar,'TooltipString','Refresh all cameras','ClickedCallback',...
                    @app.refreshCameraBrowser);
                uipushtool('Parent',toolbar,'TooltipString','Get and Initialize chosen cameras','ClickedCallback',...
                    @app.GetAndInitializeChosenCamera);
                uipushtool('Parent',toolbar,'TooltipString','Set chosen cameras','ClickedCallback',...
                    @app.SetChosenCamera);
                uipushtool('Parent',toolbar,'TooltipString','Preview','ClickedCallback',...
                    @app.PreviewCamera);
                uipushtool('Parent',toolbar,'TooltipString','Start','ClickedCallback',...
                    @app.StartCamera);
                uipushtool('Parent',toolbar,'TooltipString','Stop','ClickedCallback',...
                    @app.StopCamera);
                
                %refresh cameraTree in browser
                refreshCameraBrowser(app,browser)
                app.subuiTree.cameraBrowser.Count = 1;
                
                %count set to 1
                app.subuiTree.cameraBrowser.obj = browser;
                app.subuiTree.cameraBrowser.Count = 1;
            end
        end
        
        function refreshCameraBrowser(app,~,~)
            %get tree-node package
            import uiextras.jTree.*;
                        
            %for sub-figure callback
            f = gcf;
            t = Tree('Parent',f);
            t.SelectionType = 'discontiguous';
            
            adaptorName = app.cameraTree.adaptor.Name;
            adaptorNumber = app.cameraTree.adaptor.Number;
            for i = 1:adaptorNumber
                % build adaptor node
                adaptornode = TreeNode('Name',adaptorName{i},'Parent',t.Root);
                
                %get cameras for this adaptor
                cameraID =  app.cameraTree.(adaptorName{i}).ID;
                cameraNumber = app.cameraTree.(adaptorName{i}).Number;
                for j = 1:cameraNumber
                    cameranode = TreeNode('Name',[adaptorName{i} '_' num2str(cameraID{j})],'Value',cameraID{j},'Parent',adaptornode);
                    
                    %get all format for this camera
                    camerainfo = app.cameraTree.([adaptorName{i} '_' num2str(cameraID{j})]);
                    supportedformat = camerainfo.SupportedFormats;
                    supportedformatNumber = length(supportedformat);
                    for k = 1:supportedformatNumber
                        TreeNode('Name',supportedformat{k},'Parent',cameranode);
                    end
                    %expand cameranode
                    cameranode.expand();
                end
                %expand adaptors
                adaptornode.expand();
            end
            %expand all
            t.Root.expand();
        end
        
        function ResetAllCameras(app)
            app.Status.Value = 'Reseting all cameras...';
            imaqreset; %reset all cameras from image acquisition tool box
            %clear app.cameraTree
            app.cameraTree = [];
            %if success status bar will get feedback
            app.Status.Value = 'All cameras has been reset!';    
        end
        
        function SearchAndGetCameras(app)
            %search for cameras
            inputs = imaqhwinfo;
            %List Adaptors Available
            adaptorsLogged = inputs.InstalledAdaptors;
            %count Adaptors number
            adaptorLoggedNumber = length(adaptorsLogged);
            %save adaptors in app.cameraTree
            app.cameraTree.adaptor.Name = adaptorsLogged;
            app.cameraTree.adaptor.Number = adaptorLoggedNumber;
            app.cameraTree.basicinfo.adaptorNumber = adaptorLoggedNumber;
            
            %initialize cameraNumber for Basicinfo
            app.cameraTree.basicinfo.cameraNumber = 0;
            
            if adaptorLoggedNumber ~= 0
                %Test all available cameras
                for i = 1:adaptorLoggedNumber
                    %get info for all cameras
                    adaptorName = adaptorsLogged{i};
                    adaptorinfo = imaqhwinfo(adaptorName);
                    cameraIDs = adaptorinfo.DeviceIDs;
                    cameraNumber = length(cameraIDs);
                    %renew total cameraNumber in basicinfo
                    app.cameraTree.basicinfo.cameraNumber = app.cameraTree.basicinfo.cameraNumber +cameraNumber;
                    
                    %record available camera for this adaptor
                    app.cameraTree.(adaptorName).ID = cameraIDs;
                    app.cameraTree.(adaptorName).Number = cameraNumber;
                    
                    if cameraNumber ~= 0
                        % Test all Format for camera
                        for j = 1:cameraNumber
                            camerainfo = imaqhwinfo(adaptorName,cameraIDs{j});
                            app.cameraTree.([adaptorName '_' num2str(cameraIDs{j})]) = camerainfo;
                        end
                    end
                end
                
                %tell users status of all camera
                app.Status.Value = ['Search End: ' num2str(app.cameraTree.adaptor.Number) ' adaptors available;  ' ...
                    num2str(app.cameraTree.basicinfo.cameraNumber) ' cameras available.'];
            else
                app.Status.Value = 'No Adaptors available, please install them first!';
            end
        end
        
        function DisableSetting(app)
            %Manu Bar
            %app.CameraBrowserButton.Enable = 'off';
            app.CameraDropDown.Enable = 'on';
            app.DefaultButton.Enable = 'off';
            app.LoadSettingButton.Enable = 'off';
            app.SaveSettingButton.Enable = 'off';
            %app.InitializeButton.Enable = 'on';
            
            %Trigger
            app.TriggerTypeDropDown.Enable = 'off';
            app.TriggerSourceDropDown.Enable = 'off';
            app.TriggerConditionDropDown.Enable = 'off';
            app.FrameperTriggerSpinner.Enable = 'off';
            app.NumberoftriggerSpinner.Enable = 'off';
            
            %ROI
            app.XcenterSpinner.Enable = 'off';
            app.WidthSpinner.Enable = 'off';
            app.YcenterSpinner.Enable = 'off';
            app.HeightSpinner.Enable = 'off';
            app.ROIButton.Enable = 'off';
            app.DefaulROItButton.Enable = 'off';
            
            %Preview panel
            app.StartPreviewButton.Enable = 'off';
            app.StopPreviewButton.Enable = 'off';
            
            %AcquirePanel
            app.StartButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            app.TriggerButton.Enable = 'off';
            app.ExportButton.Enable = 'off';
            
            %CameraSetting Panel
            app.GeneralSettingButton.Enable = 'off';
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function CamsetMainSizeChanged(app, event)
            position = app.CamsetMain.Position;
            app.Status.Position = [0 0 position(3) 22];
            app.MainTabGroup.Position = [0 position(4)-125 position(3) 125];
            app.MainPanel.Position = [0 22 position(3) position(4)-125-22];
        end

        % Callback function
        function HomeTabSizeChanged(app, event)
            position = app.HomeTab.Position;
            app.SearchCameraPanel.Position = [0 0 0.24*position(3) position(4)];
            app.ChooseCameraPanel.Position = [0.24*position(3) 0 0.36*position(3) position(4)];
            app.ControlPanel.Position = [0.6*position(3) 0 0.36*position(3) position(4)];
        end

        % Callback function
        function MainPanelSizeChanged(app, event)
            position = app.MainPanel.Position;
            if ~isempty(app.PreviewPanel)
                app.PreviewPanel.Position = [0 0 0.3*(position(3)-205) 70];
            end
            if ~isempty(app.ImageShow)
                app.ImageShow.Position = [0 70 position(3)-205 position(4)-70];
            end
            if ~isempty(app.AcquirePanel)
                app.AcquirePanel.Position = [0.3*(position(3)-205) 0 0.4*(position(3)-205) 70];
            end
            if ~isempty(app.CameraSettingPanel)
                app.CameraSettingPanel.Position = [0.7*(position(3)-205) 0 0.3*(position(3)-205) 70];
            end
            if ~isempty(app.ROIPanel)
                app.ROIPanel.Position = [position(3)-205 position(4)/2 205 position(4)/2 ];
            end
            if ~isempty(app.TriggerPanel)
                app.TriggerPanel.Position = [position(3)-205 0 2015 position(4)/2];
            end
        end

        % Value changed function: SetButton
        function SetButtonValueChanged(app, event)
            value = app.SetButton.Value;
            if value
                % value = 1 means get camera
                % Enable setting panel to set
                EnableSetting(app);
                % change name of itself to done
                app.SetButton.Text = 'Done';
                % change lamp color
                app.ConnectingLamp.Color = [0 1 0];
                % change
                
                %Refresh Setting
                refreshSetting(app);
            else
                DisableSetting(app);
                app.SetButton.Text = 'Set';
                % change lamp color
                app.ConnectingLamp.Color = [1 0 0];
            end
        end

        % Button pushed function: ROIButton
        function ROIButtonPushed(app, event)
            valueXC = app.XcenterSpinner.Value;
            valueYC = app.YcenterSpinner.Value;
            valueW = app.WidthSpinner.Value;
            valueH = app.HeightSpinner.Value;
            %get cameraName
            cameraName = app.CameraDropDown.Value;
            SPcameraName = split(cameraName,'_');
            adaptorName = SPcameraName(1);
            
            %get current vid
            curvid = app.vid.(cameraName);
          
            if strcmp(curvid.Running,'off')
                
                if strcmp(adaptorName, 'pcocameraadaptor')
                    %For PCO camera, we use UserData readout and read-in ROI data
                    UD = curvid.UserData;
                    UD.ROIposition = [valueXC-valueW/2,valueYC-valueH/2,valueW,valueH];
                    curvid.UserData = UD;
                else
                    %Fot other camera we Directly use vid.ROIposition save and read-out ROI info
                    curvid.ROIposition = [valueXC-valueW/2,valueYC-valueH/2,valueW,valueH];
                end
                refreshSetting(app);   
                
                %for refreshing ROI
                if strcmp(adaptorName, 'pcocameraadaptor')
                    UD = curvid.UserData;
                    ROIout = UD.ROIposition;
                else
                    ROIout = curvid.ROIposition;
                end
                
                valueXL = ROIout(1); valueYB = ROIout(2); valueW = ROIout(3); valueH= ROIout(4);
                app.Status.Value = ['ROI of ' cameraName ' has been set to: ' 'X_center: ' num2str(valueXL+valueW/2)...
                    '  Y_center: ' num2str(valueYB+valueH/2) '  Width: ' num2str(valueW) '  Height: ' num2str(valueH)];
            else
                app.Status.Value = 'camera is running, cannot set ROI';
            end
            
        end

        % Button pushed function: CameraBrowserButton
        function CameraBrowserButtonPushed(app, event)
            position = app.CamsetMain.Position;
            cameraBrowser(app,position);
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            %reset all cameras
            app.ResetAllCameras();
            %Searcha and get all available cameras, put in app.cameraTree
            app.SearchAndGetCameras();
        end

        % Button pushed function: GeneralSettingButton
        function GeneralSettingButtonPushed(app, event)
            %get cameraName
            cameraName = app.CameraDropDown.Value;
            %get current vid
            curvid = app.vid.(cameraName);
            %get source
            cursrc = getselectedsource(curvid);
            inspect(cursrc);
        end

        % Value changed function: TriggerTypeDropDown
        function TriggerTypeDropDownValueChanged(app, event)
            value = app.TriggerTypeDropDown.Value;
            %get cameraName
            cameraName = app.CameraDropDown.Value;
            %get current vid
            curvid = app.vid.(cameraName);
            
            %get all possible trigger settting
            triggerSets = triggerinfo(curvid,value);
            triggerSets_array = squeeze(struct2cell(triggerSets));
            app.TriggerConditionDropDown.Items = unique(triggerSets_array(2,:));
            app.TriggerSourceDropDown.Items = unique(triggerSets_array(3,:));
            
            %choose first as default
            STM = triggerSets(1);
            
            %setting trigger
            triggerconfig(curvid,STM.TriggerType,STM.TriggerCondition,STM.TriggerSource);
            
            refreshSetting(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            %get cameraName
            cameraName = app.CameraDropDown.Value;
            %get current vid
            selectedvid = app.vid.(cameraName);
            if strcmp(selectedvid.Running,'off')
                UD = selectedvid.UserData;
                UD.ContinueQ = 1;
                selectedvid.UserData = UD;
                
            %                 src = getselectedsource(selectedvid);
            %                 src.RDIDoubleImageMode = 'off';
            %                 src.RDIDoubleImageMode = 'on';
                
                start(selectedvid);
                
                % turn on stop and trigger button
                app.StartButton.Enable = 'off';
                app.StopButton.Enable = 'on';
                app.TriggerButton.Enable = 'on';
                app.ExportButton.Enable = 'off';
                
            else
                app.Status.Value = 'Already start, waiting for triggers!';
            end
        end

        % Button pushed function: TriggerButton
        function TriggerButtonPushed(app, event)
            %get cameraName
            cameraName = app.CameraDropDown.Value;
            %get current vid
            selectedvid = app.vid.(cameraName);
            if strcmp(selectedvid.Running,'on')
                
                trigger(selectedvid);
          
            else
                app.Status.Value = 'Already stopped, cannot trigger!';
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %get cameraName
            cameraName = app.CameraDropDown.Value;
            %get current vid
            selectedvid = app.vid.(cameraName);
            
            if strcmp(selectedvid.Running,'on')
                UD = selectedvid.UserData;
                UD.ContinueQ = 0;
                selectedvid.UserData = UD;
                app.Status.Value = 'Stopping...';
                stop(selectedvid);
                app.Status.Value = 'Camera has been stopped!';
                app.StartButton.Enable = 'on';
                app.StopButton.Enable = 'off';
                app.TriggerButton.Enable = 'off';
                
            else
                app.Status.Value = 'Already stopped!';
            end
            
            
        end

        % Callback function
        function SearchCameraPanelSizeChanged(app, event)
            position = app.SearchCameraPanel.Position;
            if ~isempty(app.InitializeButton)
                app.InitializeButton.Position = [5 5 (position(3)-15)/2 position(4)-30];
            end
            if ~isempty(app.CameraBrowserButton)
                app.CameraBrowserButton.Position = [(position(3)-15)/2+10 5 (position(3)-15)/2 position(4)-30];
            end
            
        end

        % Button pushed function: StartPreviewButton
        function StartPreviewButtonPushed(app, event)
            %get cameraName
            cameraName = app.CameraDropDown.Value;
            %get current vid
            selectedvid = app.vid.(cameraName);
            
            
            if strcmp(selectedvid.TriggerType,'immediate')
            %                 vidRes = get(selectedvid, 'VideoResolution');
            %                 nBands = get(selectedvid, 'NumberOfBands');
            %                 hImage = image(app.ImageShow,zeros(vidRes(2), vidRes(1), nBands));
                
                preview(selectedvid);
                app.Status.Value = 'Previewing...';
            else
                app.Status.Value = ['Please set ' cameraName ' TriggerType as "Immediate"'];
            end
            
           
        end

        % Value changed function: TriggerConditionDropDown
        function TriggerConditionDropDownValueChanged(app, event)
            type = app.TriggerTypeDropDown.Value;
            condition = app.TriggerConditionDropDown.Value;
            source = app.TriggerSourceDropDown.Value;
            
            %get cameraName
            cameraName = app.CameraDropDown.Value;
            %get current vid
            curvid = app.vid.(cameraName);
            
            %setting trigger
            triggerconfig(curvid,type,condition,source);
            
            refreshSetting(app);
        end

        % Value changed function: TriggerSourceDropDown
        function TriggerSourceDropDownValueChanged(app, event)
            type = app.TriggerTypeDropDown.Value;
            condition = app.TriggerConditionDropDown.Value;
            source = app.TriggerSourceDropDown.Value;
            
            %get cameraName
            cameraName = app.CameraDropDown.Value;
            %get current vid
            curvid = app.vid.(cameraName);
            
            %setting trigger
            triggerconfig(curvid,type,condition,source);
            
            refreshSetting(app);
        end

        % Value changed function: FrameperTriggerSpinner
        function FrameperTriggerSpinnerValueChanged(app, event)
            value = app.FrameperTriggerSpinner.Value;
            
            %get cameraName
            cameraName = app.CameraDropDown.Value;
            %get current vid
            curvid = app.vid.(cameraName);
            
            %setting FramePertrigger
            curvid.FramesPerTrigger = value;
            
            refreshSetting(app);
        end

        % Button pushed function: DefaulROItButton
        function DefaulROItButtonPushed(app, event)
            %get cameraName
            cameraName = app.CameraDropDown.Value;
            SPcameraName = split(cameraName,'_');
            adaptorName = SPcameraName(1);
            
            %get current vid
            curvid = app.vid.(cameraName);
            
            %get videoresolution
            Reso = curvid.VideoResolution;
            valueW = Reso(1);
            valueH = Reso(2);
            
            if strcmp(curvid.Running,'off')
                
                if strcmp(adaptorName, 'pcocameraadaptor')
                    %For PCO camera, we use UserData readout and read-in ROI data
                    UD = curvid.UserData;
                    UD.ROIposition = [0,0,valueW,valueH];
                    curvid.UserData = UD;
                else
                    %Fot other camera we Directly use vid.ROIposition save and read-out ROI info
                    curvid.ROIposition = [0,0,valueW,valueH];
                end
                refreshSetting(app);   
                
                %for refreshing ROI
                if strcmp(adaptorName, 'pcocameraadaptor')
                    UD = curvid.UserData;
                    ROIout = UD.ROIposition;
                else
                    ROIout = curvid.ROIposition;
                end
                
                valueXL = ROIout(1); valueYB = ROIout(2); valueW = ROIout(3); valueH= ROIout(4);
                app.Status.Value = ['ROI of ' cameraName ' has been set to: ' 'X_center: ' num2str(valueXL+valueW/2)...
                    '  Y_center: ' num2str(valueYB+valueH/2) '  Width: ' num2str(valueW) '  Height: ' num2str(valueH)];
            else
                app.Status.Value = 'camera is running, cannot set ROI';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CamsetMain = uifigure('Visible', 'off');
            app.CamsetMain.Name = 'Camset';
            app.GridLayout = uigridlayout(app.CamsetMain);
            app.MainTabGroup = uitabgroup(app.GridLayout);
            app.HomeTab = uitab(app.MainTabGroup);
            app.HomeTab.Title = 'Home';
            app.GridLayout7 = uigridlayout(app.HomeTab);
            app.SearchCameraPanel = uipanel(app.GridLayout7);
            app.SearchCameraPanel.Title = 'Search Camera';
            app.GridLayout8 = uigridlayout(app.SearchCameraPanel);
            app.InitializeButton = uibutton(app.GridLayout8, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Icon = 'Initialize.png';
            app.InitializeButton.Text = 'Initialize';
            app.CameraBrowserButton = uibutton(app.GridLayout8, 'push');
            app.CameraBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @CameraBrowserButtonPushed, true);
            app.CameraBrowserButton.Icon = 'cameraIcon.png';
            app.CameraBrowserButton.Text = {'Camera'; 'Browser'};
            app.ChooseCameraPanel = uipanel(app.GridLayout7);
            app.ChooseCameraPanel.Title = 'Choose camera';
            app.GridLayout9 = uigridlayout(app.ChooseCameraPanel);
            app.SetButton = uibutton(app.GridLayout9, 'state');
            app.SetButton.ValueChangedFcn = createCallbackFcn(app, @SetButtonValueChanged, true);
            app.SetButton.Enable = 'off';
            app.SetButton.Text = 'Set';
            app.ConnectingLamp = uilamp(app.GridLayout9);
            app.CameraDropDown = uidropdown(app.GridLayout9);
            app.CameraDropDown.Enable = 'off';
            app.CameraDropDownLabel = uilabel(app.GridLayout9);
            app.CameraDropDownLabel.Text = 'Camera';
            app.ControlPanel = uipanel(app.GridLayout7);
            app.ControlPanel.Title = 'Control';
            app.GridLayout10 = uigridlayout(app.ControlPanel);
            app.DefaultButton = uibutton(app.GridLayout10, 'push');
            app.DefaultButton.Enable = 'off';
            app.DefaultButton.Text = 'Default';
            app.LoadSettingButton = uibutton(app.GridLayout10, 'push');
            app.LoadSettingButton.Enable = 'off';
            app.LoadSettingButton.Text = 'Load Setting';
            app.SaveSettingButton = uibutton(app.GridLayout10, 'push');
            app.SaveSettingButton.Enable = 'off';
            app.SaveSettingButton.Text = 'Save Setting';
            app.MoreTab = uitab(app.MainTabGroup);
            app.MoreTab.Title = 'More';
            app.Status = uieditfield(app.GridLayout, 'text');
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.StartPreviewButton = uibutton(app.GridLayout2, 'push');
            app.StartPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @StartPreviewButtonPushed, true);
            app.StartPreviewButton.Enable = 'off';
            app.StartPreviewButton.Text = 'Start Preview';
            app.StopPreviewButton = uibutton(app.GridLayout2, 'push');
            app.StopPreviewButton.Enable = 'off';
            app.StopPreviewButton.Text = 'Stop Preview';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.StartButton = uibutton(app.GridLayout3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.TriggerButton = uibutton(app.GridLayout3, 'push');
            app.TriggerButton.ButtonPushedFcn = createCallbackFcn(app, @TriggerButtonPushed, true);
            app.TriggerButton.Enable = 'off';
            app.TriggerButton.Text = 'Trigger';
            app.StopButton = uibutton(app.GridLayout3, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.ExportButton = uibutton(app.GridLayout3, 'push');
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export...';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GeneralSettingButton = uibutton(app.GridLayout4, 'push');
            app.GeneralSettingButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralSettingButtonPushed, true);
            app.GeneralSettingButton.Enable = 'off';
            app.GeneralSettingButton.Text = {'General'; 'Setting'};
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.DefaulROItButton = uibutton(app.GridLayout5, 'push');
            app.DefaulROItButton.ButtonPushedFcn = createCallbackFcn(app, @DefaulROItButtonPushed, true);
            app.DefaulROItButton.Enable = 'off';
            app.DefaulROItButton.Text = 'Default';
            app.ROIButton = uibutton(app.GridLayout5, 'push');
            app.ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIButton.Enable = 'off';
            app.ROIButton.Text = 'ROI';
            app.XcenterSpinner = uispinner(app.GridLayout5);
            app.XcenterSpinner.Limits = [1 Inf];
            app.XcenterSpinner.ValueDisplayFormat = '%.1f';
            app.XcenterSpinner.Enable = 'off';
            app.XcenterSpinner.Value = 200;
            app.XcenterSpinnerPCOLabel = uilabel(app.GridLayout5);
            app.XcenterSpinnerPCOLabel.Text = 'X center';
            app.YcenterSpinner = uispinner(app.GridLayout5);
            app.YcenterSpinner.Limits = [1 Inf];
            app.YcenterSpinner.ValueDisplayFormat = '%.1f';
            app.YcenterSpinner.Enable = 'off';
            app.YcenterSpinner.Value = 200;
            app.YcenterSpinnerPCOLabel = uilabel(app.GridLayout5);
            app.YcenterSpinnerPCOLabel.Text = 'Y center';
            app.WidthSpinner = uispinner(app.GridLayout5);
            app.WidthSpinner.Limits = [1 Inf];
            app.WidthSpinner.ValueDisplayFormat = '%.1f';
            app.WidthSpinner.Enable = 'off';
            app.WidthSpinner.Value = 50;
            app.WidthSpinnerPCOLabel = uilabel(app.GridLayout5);
            app.WidthSpinnerPCOLabel.Text = 'Width';
            app.HeightSpinner = uispinner(app.GridLayout5);
            app.HeightSpinner.Limits = [1 Inf];
            app.HeightSpinner.ValueDisplayFormat = '%.1f';
            app.HeightSpinner.Enable = 'off';
            app.HeightSpinner.Value = 50;
            app.HeightSpinnerPCOLabel = uilabel(app.GridLayout5);
            app.HeightSpinnerPCOLabel.Text = 'Height';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.TriggerTypeDropDown = uidropdown(app.GridLayout6);
            app.TriggerTypeDropDown.Items = {'immediate', 'manual', 'hardware'};
            app.TriggerTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerTypeDropDownValueChanged, true);
            app.TriggerTypeDropDown.Enable = 'off';
            app.TriggerTypeDropDown.Value = 'immediate';
            app.TriggerTypeDropDownPCOLabel = uilabel(app.GridLayout6);
            app.TriggerTypeDropDownPCOLabel.Text = 'Trigger Type:';
            app.TriggerConditionDropDown = uidropdown(app.GridLayout6);
            app.TriggerConditionDropDown.Items = {'None', ''};
            app.TriggerConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerConditionDropDownValueChanged, true);
            app.TriggerConditionDropDown.Enable = 'off';
            app.TriggerConditionDropDown.Value = 'None';
            app.ConditionLabel = uilabel(app.GridLayout6);
            app.ConditionLabel.Text = 'Condition';
            app.TriggerSourceDropDown = uidropdown(app.GridLayout6);
            app.TriggerSourceDropDown.Items = {'none', 'ExternExposureStart'};
            app.TriggerSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerSourceDropDownValueChanged, true);
            app.TriggerSourceDropDown.Enable = 'off';
            app.TriggerSourceDropDown.Value = 'none';
            app.SourceDropDownLabel = uilabel(app.GridLayout6);
            app.SourceDropDownLabel.Text = 'Source';
            app.NumberoftriggerSpinner = uispinner(app.GridLayout6);
            app.NumberoftriggerSpinner.Limits = [1 Inf];
            app.NumberoftriggerSpinner.Enable = 'off';
            app.NumberoftriggerSpinner.Value = 1;
            app.NumberoftriggerSpinnerPCOLabel = uilabel(app.GridLayout6);
            app.NumberoftriggerSpinnerPCOLabel.Text = 'Number of trigger';
            app.FrameperTriggerSpinner = uispinner(app.GridLayout6);
            app.FrameperTriggerSpinner.Limits = [1 Inf];
            app.FrameperTriggerSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameperTriggerSpinnerValueChanged, true);
            app.FrameperTriggerSpinner.Enable = 'off';
            app.FrameperTriggerSpinner.Value = 1;
            app.FrameperTriggerSpinnerPCOLabel = uilabel(app.GridLayout6);
            app.FrameperTriggerSpinnerPCOLabel.Text = 'Frame per Trigger';
            app.ImageShow = uiaxes(app.GridLayout);
            % Show the figure after all components are created
            app.CamsetMain.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Camset

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CamsetMain)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CamsetMain)
        end
    end
end