classdef Vuima < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VuimaMain                     matlab.ui.Figure
        MainTabGroup                  matlab.ui.container.TabGroup
        HomeTab                       matlab.ui.container.Tab
        LinkingPanel                  matlab.ui.container.Panel
        LinkingLamp                   matlab.ui.control.Lamp
        LinkButton                    matlab.ui.control.Button
        imastDropDownLabel            matlab.ui.control.Label
        imastDropDown                 matlab.ui.control.DropDown
        SavingPanel                   matlab.ui.container.Panel
        Browser                       matlab.ui.control.Button
        SelectFolderEditFieldPGLabel  matlab.ui.control.Label
        SelectFolder                  matlab.ui.control.EditField
        FileNameEditFieldPGLabel      matlab.ui.control.Label
        FileName                      matlab.ui.control.EditField
        DoneButton                    matlab.ui.control.Button
        AutoSave                      matlab.ui.control.CheckBox
        CheckImagePanel               matlab.ui.container.Panel
        DropDown                      matlab.ui.control.DropDown
        CheckButton                   matlab.ui.control.Button
        Spinner                       matlab.ui.control.Spinner
        ImJButton                     matlab.ui.control.Button
        LUTDropDown                   matlab.ui.control.DropDown
        ViewTab                       matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        expoEditFieldLabel            matlab.ui.control.Label
        Expo                          matlab.ui.control.NumericEditField
        BinEditFieldLabel             matlab.ui.control.Label
        Bin                           matlab.ui.control.NumericEditField
        ModifyEditFieldLabel          matlab.ui.control.Label
        Condi                         matlab.ui.control.NumericEditField
        CsatEditFieldLabel            matlab.ui.control.Label
        Csat                          matlab.ui.control.NumericEditField
        Panel2                        matlab.ui.container.Panel
        LxEditFieldLabel              matlab.ui.control.Label
        Lx                            matlab.ui.control.NumericEditField
        WEditFieldLabel               matlab.ui.control.Label
        W                             matlab.ui.control.NumericEditField
        UyEditFieldLabel              matlab.ui.control.Label
        Uy                            matlab.ui.control.NumericEditField
        HEditFieldLabel               matlab.ui.control.Label
        H                             matlab.ui.control.NumericEditField
        LModifyEditFieldLabel         matlab.ui.control.Label
        LModify                       matlab.ui.control.NumericEditField
        FittingData                   matlab.ui.control.Table
        Status                        matlab.ui.control.EditField
        FittingPanel                  matlab.ui.container.Panel
        fittingY                      matlab.ui.control.UIAxes
        fittingX                      matlab.ui.control.UIAxes
        FittingSettingPanel           matlab.ui.container.Panel
        Auto                          matlab.ui.control.CheckBox
        FitButton                     matlab.ui.control.Button
        FittingModeDropDown           matlab.ui.control.DropDown
        FittingSettingButton          matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        pksSpinnerLabel               matlab.ui.control.Label
        pks                           matlab.ui.control.Spinner
        ImageShow                     matlab.ui.control.UIAxes
        Light                         matlab.ui.control.NumericEditField
        QuickFitting                  matlab.ui.container.Panel
        peakEditFieldLabel            matlab.ui.control.Label
        peak                          matlab.ui.control.NumericEditField
        xcEditFieldLabel              matlab.ui.control.Label
        xc                            matlab.ui.control.NumericEditField
        ycEditFieldLabel              matlab.ui.control.Label
        yc                            matlab.ui.control.NumericEditField
        sigxEditFieldLabel            matlab.ui.control.Label
        sigx                          matlab.ui.control.NumericEditField
        sigyEditFieldLabel            matlab.ui.control.Label
        sigy                          matlab.ui.control.NumericEditField
        peak_2                        matlab.ui.control.NumericEditField
        xc_2                          matlab.ui.control.NumericEditField
        yc_2                          matlab.ui.control.NumericEditField
        sigx_2                        matlab.ui.control.NumericEditField
        sigy_2                        matlab.ui.control.NumericEditField
        AutoIniFit                    matlab.ui.control.CheckBox
        ErrorData                     matlab.ui.control.Table
        CountRate                     matlab.ui.control.NumericEditField
        odValue                       matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        camima % main camima handle
        imast % image stream
        curimage %present image data, used to show and do fitting
        nextimage % a counter for next picture group coming in
        Equation % fit equation
        opt % fit options
        otherimage % is a struct including UserDifined images, like BG, Light, Atom and more
        curFileNum %current file number
        curBin %binning of current image
    end
    
    methods (Access = public)
        
        function [fitresult, gof] = dofit(~,XData, YData, ZData, equation, opts)
            %% Fit
            [xData, yData, zData] = prepareSurfaceData(XData, YData, ZData);
            
            % Set up fittype and options.
            ft = fittype(equation{1}, 'independent', {'x', 'y'}, 'dependent', 'z' );
                                    
            % Fit model to data.
            [fitresult, gof] = fit( [xData, yData], zData, ft, opts );
            
            %make fitting parallel
            %f = parfeval(@fit,2,[xData, yData], zData, ft, opts);
            %[fitresult, gof] = fetchOutputs(f);
        end
        

        function [Xdata, Ydata, Zdata, peak, xc, yc, xs, ys] = image2data(~, data)
            %data = app.curimage;
            [xs,ys] = size(data);           
            Zdata = data(:)';         
            Xdata = repmat(1:xs,1,ys);
            Ydata = repelem(1:ys,xs);
            % peak and canter more info from preprocess
            [peak,peakPosition] = max(data(:));
            [xc,yc] = ind2sub(size(data),peakPosition);
        end
        
        function fit(app)
           %run fitting in parallel
           
           %run fitting with GPU and more
           
           %parfeval(@fitCore,0,app);
           fitCore(app);
        end
        
        function fitCore(app)
            %get model to fitting
            model = app.FittingModeDropDown.Value;
            
            switch model
                case 'Customed'
                    fitCustomed(app);
                case 'Gaussian'
                    fitGaussian(app);
                case 'Multi-Gaussian'
                    fitMultiGaussian(app);
                case 'DG-Concentric'
                    fitDGConcentric(app);
                case 'Parabola'
                    fitParabola(app);
                case 'ParabolaGaussian'
                    ParabolaGaussian(app);
                case 'DG'
                    fitDoubleGaussian(app);
            end            
        end
        
        function saveimage(app)
            
            imwrite(app.otherimage.IMG1,[app.SelectFolder.Value '\' num2str(app.nextimage,'%03d') '-' app.FileName.Value '-1.tiff'],'tiff','Description',[num2str(app.curBin.x) '-' num2str(app.curBin.y)]);
            imwrite(app.otherimage.IMG2,[app.SelectFolder.Value '\' num2str(app.nextimage,'%03d') '-' app.FileName.Value '-2.tiff'],'tiff','Description',[num2str(app.curBin.x) '-' num2str(app.curBin.y)]);
            imwrite(app.otherimage.IMG3,[app.SelectFolder.Value '\' num2str(app.nextimage,'%03d') '-' app.FileName.Value '-3.tiff'],'tiff','Description',[num2str(app.curBin.x) '-' num2str(app.curBin.y)]);
            
            imwrite(uint16(app.curimage),[app.SelectFolder.Value '\' num2str(app.nextimage,'%03d') '-' app.FileName.Value '-OD.tiff'],'tiff','Description',[num2str(app.curBin.x) '-' num2str(app.curBin.y)]);
            %refresh spinner
            app.curFileNum = app.nextimage;
            app.Spinner.Value = app.curFileNum;
            app.nextimage = app.nextimage + 1;
        end
        
        function fitGaussian(app)
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
           
            %set gaussian equation
            equation = {'bg + peak * exp( - 0.5* (x-xc)^2 / sigx^2 -0.5* (y-yc)^2/sigy^2)'};
            
            %set local equation to app.Equation
            app.Equation = equation;
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [1000 1e05 Inf Inf xs ys];
            opts.Lower = [0 30 0 0 1 1];
            
            if app.AutoIniFit.Value
                opts.StartPoint = [200 peak 10 10 xc yc];
            else
                opts.StartPoint = [200 app.peak.Value app.sigy.Value app.sigx.Value app.yc.Value app.xc.Value];
            end
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 20;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %set local fitting opts to app.opt
            app.opt = opts;
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [fitresult, gof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,fitresult,gof,xs,ys,xc,yc);
            
            %save fitting result
            coValue = coeffvalues(fitresult);
            fitfilename = [app.SelectFolder.Value '\' app.FileName.Value '.csv'];            
            dlmwrite(fitfilename,[0,app.curFileNum,coValue(2),app.curBin.x*coValue(4),app.curBin.y*coValue(3),coValue(6),coValue(5),app.Light.Value],'-append');
            
            
            %show original od for checking whether this picture is out of range of probe light couinting
            %show original o.d.
            syms od;
            CountRat = app.CountRate.Value;
            CountSat = app.Csat.Value;
            OD = coValue(2)/1000;
            eqn = OD == od + (1 - exp(-od)) * CountRat / CountSat;
            solod = solve(eqn,od);
            app.odValue.Value = double(solod)*1000;
            
        end
        
        function fitCustomed(app)            
            %prepare data
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [fitresult, gof] = dofit(app, XData, YData, ZData, app.Equation, app.opt);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,fitresult,xs,ys,xc,yc);
        end
        
        function fitMultiGaussian(app)
            %get number of peaks
            pks = app.pks.Value;
            
            % 
        end
        
        function fitDGConcentric(app)
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
            
            %set initial peakA and peakB
            peakA = peak*4/5;
            peakB = peak/5;   % here we use a guess of one third total peak to get lower gaussian's peak
            
            %sigxA and sigyA    and    sigxB and sigyB
            sigxA = 1.5;
            sigyA = 1.5;
            
            sigxB = 7;
            sigyB = 7;
            
            %set gaussian equation
            equation = {'bg + peakA * exp( - 0.5* (x-xcA)^2 / sigxA^2 -0.5* (y-ycA)^2/sigyA^2) + peakB * exp( - 0.5* (x-xcB)^2 / sigxB^2 -0.5* (y-ycB)^2/sigyB^2)'};
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [1000 1e05 1e05 Inf Inf Inf Inf xs xs ys ys];
            opts.Lower = [0 30 30 0 0 0 0 1 1 1 1];
            opts.StartPoint = [200 peakA peakB sigxA sigxB sigyA sigyB xc xc yc yc];  %because of concentric we set same xc and yc for two gaussian
            
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 20;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [fitresult, gof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,fitresult,gof,xs,ys,xc,yc);
            
            %save fitting result
            coValue = coeffvalues(fitresult);
            fitfilenameA = [app.SelectFolder.Value '\' app.FileName.Value 'A.csv'];
            fitfilenameB = [app.SelectFolder.Value '\' app.FileName.Value 'B.csv'];
            dlmwrite(fitfilenameA,[0,app.curFileNum,coValue(2),app.curBin.x*coValue(6),app.curBin.y*coValue(4),coValue(10),coValue(8),app.Light.Value],'-append');
            dlmwrite(fitfilenameB,[0,app.curFileNum,coValue(3),app.curBin.x*coValue(7),app.curBin.y*coValue(5),coValue(11),coValue(9),app.Light.Value],'-append');            

            
            %show original od for checking whether this picture is out of range of probe light couinting
            %show original o.d.
            syms od;
            CountRat = app.CountRate.Value;
            CountSat = app.Csat.Value;
            OD = (coValue(2) + coValue(3))/1000;
            eqn = OD == od + (1 - exp(-od)) * CountRat / CountSat;
            solod = solve(eqn,od);
            app.odValue.Value = double(solod)*1000;
            
        end        
        
        
        function showFit(app,fitresult,gof,xs,ys,xc,yc)
            %reconstruct function and data for showing
            coNumber = numcoeffs(fitresult);
            coName = coeffnames(fitresult);
            coValue = coeffvalues(fitresult);
            
            % show fitting result
            app.FittingData.Data = [coName num2cell(coValue')];
            
            %reconstruct error for showing in list
            ErrName = fieldnames(gof);
            ErrValue = cellfun(@(f)num2cell(getfield(gof,f)),ErrName);
            
            %show error result
            app.ErrorData.Data = [ErrName ErrValue];
            
            %construct coeff string
            costr = coName{1};
            if coNumber > 1
                for i = 2:coNumber
                    costr = [costr ',' coName{i}];
                end
            end
            
            %build function for plot
            fy = str2func(vectorize(['@(x) @(' costr ') @(y)' formula(fitresult)]));
            fx = str2func(vectorize(['@(y) @(' costr ') @(x)' formula(fitresult)]));

            %plot x-direction
            fytemp = fy(xc);
            arg = num2cell(coValue);
            f = fytemp(arg{:});     
            app.fittingX.XLim = [0.5 ys+0.5];
            plot(app.fittingX,1:ys,f(1:ys),1:ys,app.curimage(xc,:));
            
            %plot y-direction
            fxtemp = fx(yc);
            arg = num2cell(coValue);
            f = fxtemp(arg{:});           
            app.fittingY.YLim = [0.5 xs+0.5];
            app.fittingY.YDir = 'reverse';
            plot(app.fittingY,f(1:xs),1:xs,app.curimage(:,yc)',1:xs);
        end
        
        function fitParabola(app)
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
            
            %Rx and Ry
            Rx = 10;
            Ry = 10;
                        
            %set gaussian equation
            equation = {'bg + peak * heaviside((1 - ((x-xc)/Rx)^2 - ((y-yc)/Ry)^2)) * (1 - ((x-xc)/Rx)^2 - ((y-yc)/Ry)^2)'};
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [Inf Inf 1000 1e05 xs ys];
            opts.Lower = [0 0 0 30 1 1];
            opts.StartPoint = [Rx Ry 200 peak xc yc];
            
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 20;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [fitresult, gof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,fitresult,gof,xs,ys,xc,yc);
            
            %save fitting result
            coValue = coeffvalues(fitresult);
            fitfilename = [app.SelectFolder.Value '\' app.FileName.Value '.csv'];            
            dlmwrite(fitfilename,[0,app.curFileNum,coValue(4),app.curBin.x*coValue(2),app.curBin.y*coValue(1),coValue(6),coValue(5),app.Light.Value],'-append');
            
            %show original od for checking whether this picture is out of range of probe light couinting
            %show original o.d.
            syms od;
            CountRat = app.CountRate.Value;
            CountSat = app.Csat.Value;
            OD = coValue(4)/1000;
            eqn = OD == od + (1 - exp(-od)) * CountRat / CountSat;
            solod = solve(eqn,od);
            app.odValue.Value = double(solod)*1000;
        end
        
        function results = cutoffAboveZero(~, x)
            %not used now
            
            if (x>0)
                results = x;
            else
                results = 0;
            end
        end
        
        function fitAiry(app)
            
            
        end
       
        function fitDoubleGaussian(app)
            
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);

            %set gaussian equation
            equation = {'bg + peakA * exp( - 0.5* (x-xcA)^2 / sigxA^2 -0.5* (y-ycA)^2/sigyA^2) + peakB * exp( - 0.5* (x-xcB)^2 / sigxB^2 -0.5* (y-ycB)^2/sigyB^2)'};
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [1000 1e05 1e05 Inf Inf Inf Inf xs xs ys ys];
            opts.Lower = [0 30 30 0 0 0 0 1 1 1 1];
            opts.StartPoint = [200 app.peak.Value app.peak_2.Value app.sigy.Value app.sigy_2.Value app.sigx.Value app.sigx_2.Value app.yc.Value app.yc_2.Value app.xc.Value app.xc_2.Value];  %because of concentric we set same xc and yc for two gaussian
            
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 20;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [fitresult, gof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,fitresult,gof,xs,ys,xc,yc);
            
            %save fitting result
            coValue = coeffvalues(fitresult);
            fitfilenameA = [app.SelectFolder.Value '\' app.FileName.Value 'A.csv'];
            fitfilenameB = [app.SelectFolder.Value '\' app.FileName.Value 'B.csv'];
            dlmwrite(fitfilenameA,[0,app.curFileNum,coValue(2),app.curBin.x*coValue(6),app.curBin.y*coValue(4),coValue(10),coValue(8),app.Light.Value],'-append');
            dlmwrite(fitfilenameB,[0,app.curFileNum,coValue(3),app.curBin.x*coValue(7),app.curBin.y*coValue(5),coValue(11),coValue(9),app.Light.Value],'-append');            

        end
        
        function ParabolaGaussian(app)
            
            %transfer image to data with tags
            [XData, YData, ZData, peak, xc, yc, xs, ys] = image2data(app,app.curimage);
            
            %set initial peakA and peakB
            peakA = peak*4/5;
            peakB = peak/5;   % here we use a guess of one third total peak to get lower gaussian's peak
            
            %sigxA and sigyA    and    sigxB and sigyB
            Rx = 2;
            Ry = 2;
            
            sigxB = 7;
            sigyB = 7;
            
            %set gaussian equation
            equation = {'bg + peakA * heaviside((1 - ((x-xcA)/Rx)^2 - ((y-ycA)/Ry)^2)) * (1 - ((x-xcA)/Rx)^2 - ((y-ycA)/Ry)^2) + peakB * exp( - 0.5* (x-xcB)^2 / sigxB^2 -0.5* (y-ycB)^2/sigyB^2)'};
            
            %set fitting options
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = 'Trust-Region';
            opts.DiffMaxChange = 10;
            opts.DiffMinChange = 1e-08;
            
            %set upper lower and startpoint which is very important for speeding up
            opts.Upper = [Inf Inf 1000 1e05 1e05 Inf Inf xs xs ys ys];
            opts.Lower = [0 0 0 30 30 0 0 1 1 1 1];
            opts.StartPoint = [Rx Ry 200 peakA peakB sigxB sigyB xc xc yc yc];  %because of concentric we set same xc and yc for two gaussian
            
            % itterating optioins
            opts.MaxFunEvals = 300;
            opts.MaxIter = 20;
            opts.Robust = 'Off';
            opts.TolFun = 1e-06;
            opts.TolX = 1e-06;
            opts.Display = 'Notify';
            
            %do fitting
            app.Status.Value = 'Start fitting... ...';
            [fitresult, gof] = dofit(app, XData, YData, ZData, equation, opts);
            app.Status.Value = 'Fitting done!';
            
            %show fitting result
            showFit(app,fitresult,gof,xs,ys,xc,yc);
            
            %save fitting result
            coValue = coeffvalues(fitresult);
            fitfilenameA = [app.SelectFolder.Value '\' app.FileName.Value 'A.csv'];
            fitfilenameB = [app.SelectFolder.Value '\' app.FileName.Value 'B.csv'];
            dlmwrite(fitfilenameA,[0,app.curFileNum,coValue(4),app.curBin.x*coValue(2),app.curBin.y*coValue(1),coValue(10),coValue(8),app.Light.Value],'-append');
            dlmwrite(fitfilenameB,[0,app.curFileNum,coValue(5),app.curBin.x*coValue(7),app.curBin.y*coValue(6),coValue(11),coValue(9),app.Light.Value],'-append');            


        end
        
    end

    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Size changed function: VuimaMain
        function VuimaMainSizeChanged(app, event)
            position = app.VuimaMain.Position;
            app.Status.Position = [0 0 position(3)-150 22];
            app.odValue.Position = [position(3)-150 0 50 22];
            app.CountRate.Position = [position(3)-100 0 50 22];
            app.Light.Position = [position(3)-50 0 50 22];
            app.MainTabGroup.Position = [0 position(4)-125 position(3) 125];
            app.FittingData.Position = [0 344 150 position(4)-22-125-150-174];
            app.ErrorData.Position = [0 196 150 150];
            app.QuickFitting.Position = [0 22 150 174];
            app.FittingPanel.Position = [150 22 position(3)-150 position(4)-22-125];
        end

        % Size changed function: HomeTab
        function HomeTabSizeChanged(app, event)
            position = app.HomeTab.Position;
            app.LinkingPanel.Position = [0 0 150 position(4)];
            app.SavingPanel.Position = [150 0 330 position(4)];
            app.CheckImagePanel.Position = [480 0 190 position(4)];
        end

        % Size changed function: ViewTab
        function ViewTabSizeChanged(app, event)
            position = app.ViewTab.Position;
        end

        % Size changed function: FittingPanel
        function FittingPanelSizeChanged(app, event)
            position = app.FittingPanel.Position;
            if ~isempty(app.ImageShow)
                app.ImageShow.Position = [0 130 position(3)-130 position(4)-130];
            end
            
            if ~isempty(app.fittingX)
                app.fittingX.Position = [0 0 position(3)-130 130];
            end
            if ~isempty(app.fittingY)
                app.fittingY.Position = [position(3)-130 130 130 position(4)-130];
            end
            if ~isempty(app.FittingSettingPanel)
                app.FittingSettingPanel.Position = [position(3)-130 0 130 130];
            end
        end

        % Button pushed function: LinkButton
        function LinkButtonPushed(app, event)
            if strcmp(app.imastDropDown.Value,'Check')
                
                %light on
                app.LinkingLamp.Color = [0 1 0];
                
                %set file folder to today
                today = split(date,'-');
                app.SelectFolder.Value = ['D:\data\' today{3} '\' today{2} '\' today{1}];
                if ~ exist(app.SelectFolder.Value,'dir')
                    mkdir(app.SelectFolder.Value);
                end
                
                %change status
                app.imastDropDown.Enable = 'off';
                app.Browser.Enable = 'on';
                app.SelectFolder.Enable = 'on';
                app.FileName.Enable = 'on';
                app.DoneButton.Enable = 'on';
                app.AutoSave.Enable = 'on';
                app.Status.Value = 'Please Check image and data!';
                
            else
                %get selected imast
                app.imast = app.imastDropDown.Value;
                %get camima main app handle
                mainapp = app.camima;
                %put local app handle to main app
                mainapp.selectedimast.(app.imast) = app;
                %light on
                app.LinkingLamp.Color = [0 1 0];
                
                %set file folder to today
                today = split(date,'-');
                app.SelectFolder.Value = ['D:\data\' today{3} '\' today{2} '\' today{1}];
                if ~ exist(app.SelectFolder.Value,'dir')
                    mkdir(app.SelectFolder.Value);
                end
                
                %change status
                app.imastDropDown.Enable = 'off';
                app.Browser.Enable = 'on';
                app.SelectFolder.Enable = 'on';
                app.FileName.Enable = 'on';
                app.DoneButton.Enable = 'on';
                app.AutoSave.Enable = 'on';
                app.Status.Value = 'Successful Linking!';
                mainapp.Status.Value = 'one vuima successful linking in!';
            end
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            fit(app);  
        end

        % Button pushed function: FittingSettingButton
        function FittingSettingButtonPushed(app, event)
            if ~isempty(app.Equation)&&~isempty(app.opt)
                fitset = Fitset;
                fitset.vuima = app;
                fitset.Equation.Value = app.Equation;
                fitset.Algorithm.Value = app.opt.Algorithm;
                fitset.DiffMaxChange.Value = app.opt.DiffMaxChange;
                fitset.DiffMinChange.Value = app.opt.DiffMinChange;
                
                coefficients = symvar(app.Equation{1});
                coefficients = coefficients(~strcmp(coefficients,'x'));
                coefficients = coefficients(~strcmp(coefficients,'y'));
                %create coTable
                StartPoint = num2cell(app.opt.StartPoint');
                Lower = num2cell(app.opt.Lower');
                Upper = num2cell(app.opt.Upper');
                %num2cell(true(7,1));
                fitset.CoTable.Enable = 'on';
                fitset.CoTable.Data = [coefficients StartPoint Lower Upper];
                
                fitset.MaxFunEvals.Value = app.opt.MaxFunEvals;
                fitset.MaxIter.Value = app.opt.MaxIter;
                fitset.Robust.Value = app.opt.Robust;
                fitset.TolFun.Value = app.opt.TolFun;
                fitset.TolX.Value = app.opt.TolX;
            else
                fitset = Fitset;
                fitset.vuima = app;
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            %check existing file in folder with this file name...
            %get the number of file then refresh gui
            files = dir([app.SelectFolder.Value '\*' app.FileName.Value '-1.tiff']);
            if ~isempty(files)
                lastFileNameSP = split(files(end).name,'-');
                lastFileNumber = str2double(lastFileNameSP{1});
                %show on
                app.Spinner.Value = lastFileNumber;
                %set currrent
                app.curFileNum = lastFileNumber;
                %set next
                app.nextimage = lastFileNumber + 1;
            else
                %show on
                app.Spinner.Value = 0;
                %set currrent
                app.curFileNum = 0;
                %set next
                app.nextimage = 1;
            end
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            value = app.DropDown.Value;
            
            switch value
                case 'Atom'
                    imaname = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-1.tiff'];
                case 'Light'
                    imaname = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-2.tiff'];
                case 'Bg'
                    imaname = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-3.tiff'];
                case 'OD'
                    imaname = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-OD.tiff'];
            end
            
            app.curFileNum = app.Spinner.Value;
            app.curimage = imread(imaname);
            imainfo = imfinfo(imaname);
            if isfield(imainfo,'ImageDescription')
                Bin = split(imainfo.ImageDescription,'-');
                app.curBin.x = str2double(Bin(1));
                app.curBin.y = str2double(Bin(2));
            end
            
            %set show aspect ratio
            imgsize = size(app.curimage);
            app.ImageShow.PlotBoxAspectRatio = [imgsize(2),imgsize(1),1];
            %imshow(app.img.(cameraName).IMGOD,'Parent',vuima.ImageShow,'XData',[1 imgsize(2)],'YData',[1 imgsize(1)]);
            imagesc(app.ImageShow,app.curimage);
        end

        % Button pushed function: Browser
        function BrowserButtonPushed(app, event)
            dname = uigetdir('D:\data');
            if dname
                app.SelectFolder.Value = dname;
            end
        end

        % Button pushed function: ImJButton
        function ImJButtonPushed(app, event)
            value = app.DropDown.Value;
              
            switch value
                case 'Atom'
                    path = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-1.tiff'];
                case 'Light'
                    path = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-2.tiff'];
                case 'Bg'
                    path = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-3.tiff'];
                case 'OD'
                    path = [app.SelectFolder.Value '\' num2str(app.Spinner.Value,'%03d') '-' app.FileName.Value '-OD.tiff'];
            end
            
            winopen(path);
        end

        % Callback function
        function LUTButtonPushed(app, event)
            value = app.LUTDropDown.Value;
            colormap(app.ImageShow,value);
        end

        % Value changed function: LUTDropDown
        function LUTDropDownValueChanged(app, event)
            value = app.LUTDropDown.Value;
            colormap(app.ImageShow,value);
        end

        % Close request function: VuimaMain
        function VuimaMainCloseRequest(app, event)
            
            %When delete a Vuima, we need to release imastr in Camima
            if ~isempty(app.camima)
                MainCamima = app.camima;
                if isfield(MainCamima.selectedimast,app.imast)
                    MainCamima.selectedimast = rmfield(MainCamima.selectedimast,app.imast);
                end
            end
            delete(app);
            
        end

        % Value changed function: odValue
        function odValueValueChanged(app, event)
            value = app.odValue.Value;
            if ((value > 2500) ||(value < 1000))
                app.odValue.BackgroundColor = [1.00,0.27,0.00];
            else
                app.odValue.BackgroundColor = [1.00,1.00,1.00];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VuimaMain = uifigure;
            app.VuimaMain.Name = 'Vuima';
            app.VuimaMain.CloseRequestFcn = createCallbackFcn(app, @VuimaMainCloseRequest, true);
            app.VuimaMain.SizeChangedFcn = createCallbackFcn(app, @VuimaMainSizeChanged, true);
            setAutoResize(app, app.VuimaMain, true)
            app.MainTabGroup = uitabgroup(app.VuimaMain);
            app.HomeTab = uitab(app.MainTabGroup);
            app.HomeTab.SizeChangedFcn = createCallbackFcn(app, @HomeTabSizeChanged, true);
            app.HomeTab.Title = 'Home';
            app.HomeTab.Units = 'pixels';
            app.LinkingPanel = uipanel(app.HomeTab);
            app.LinkingPanel.Title = 'Linking';
            app.LinkingLamp = uilamp(app.LinkingPanel);
            app.LinkButton = uibutton(app.LinkingPanel, 'push');
            app.LinkButton.ButtonPushedFcn = createCallbackFcn(app, @LinkButtonPushed, true);
            app.LinkButton.Text = 'Link';
            app.imastDropDownLabel = uilabel(app.LinkingPanel);
            app.imastDropDownLabel.Enable = 'off';
            app.imastDropDownLabel.Text = 'imast';
            app.imastDropDown = uidropdown(app.LinkingPanel);
            app.imastDropDown.Items = {'Check'};
            app.imastDropDown.Value = 'Check';
            app.SavingPanel = uipanel(app.HomeTab);
            app.SavingPanel.Title = 'Saving';
            app.Browser = uibutton(app.SavingPanel, 'push');
            app.Browser.ButtonPushedFcn = createCallbackFcn(app, @BrowserButtonPushed, true);
            app.Browser.Enable = 'off';
            app.Browser.Text = 'Browser';
            app.SelectFolderEditFieldPGLabel = uilabel(app.SavingPanel);
            app.SelectFolderEditFieldPGLabel.Text = 'Select  Folder';
            app.SelectFolder = uieditfield(app.SavingPanel, 'text');
            app.SelectFolder.Enable = 'off';
            app.FileNameEditFieldPGLabel = uilabel(app.SavingPanel);
            app.FileNameEditFieldPGLabel.Text = 'File Name';
            app.FileName = uieditfield(app.SavingPanel, 'text');
            app.FileName.Enable = 'off';
            app.DoneButton = uibutton(app.SavingPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            app.AutoSave = uicheckbox(app.SavingPanel);
            app.AutoSave.Enable = 'off';
            app.AutoSave.Text = {'Auto'; 'Save'};
            app.CheckImagePanel = uipanel(app.HomeTab);
            app.CheckImagePanel.Title = 'Check Image';
            app.DropDown = uidropdown(app.CheckImagePanel);
            app.DropDown.Items = {'Atom', 'Light', 'Bg', 'OD'};
            app.DropDown.Value = 'Atom';
            app.CheckButton = uibutton(app.CheckImagePanel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.Spinner = uispinner(app.CheckImagePanel);
            app.ImJButton = uibutton(app.CheckImagePanel, 'push');
            app.ImJButton.ButtonPushedFcn = createCallbackFcn(app, @ImJButtonPushed, true);
            app.ImJButton.Text = 'ImJ';
            app.LUTDropDown = uidropdown(app.CheckImagePanel);
            app.LUTDropDown.Items = {'jet', 'gray', 'parula', 'hsv', 'hot', 'cool', 'spring'};
            app.LUTDropDown.Editable = 'on';
            app.LUTDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTDropDownValueChanged, true);
            app.LUTDropDown.Value = 'jet';
            app.ViewTab = uitab(app.MainTabGroup);
            app.ViewTab.SizeChangedFcn = createCallbackFcn(app, @ViewTabSizeChanged, true);
            app.ViewTab.Title = 'View';
            app.ViewTab.Units = 'pixels';
            app.Panel = uipanel(app.ViewTab);
            app.Panel.Title = 'Panel';
            app.expoEditFieldLabel = uilabel(app.Panel);
            app.expoEditFieldLabel.Text = 'expo';
            app.Expo = uieditfield(app.Panel, 'numeric');
            app.Expo.Limits = [1 Inf];
            app.Expo.Value = 1;
            app.BinEditFieldLabel = uilabel(app.Panel);
            app.BinEditFieldLabel.Text = 'Bin';
            app.Bin = uieditfield(app.Panel, 'numeric');
            app.Bin.Limits = [1 2];
            app.Bin.Value = 2;
            app.ModifyEditFieldLabel = uilabel(app.Panel);
            app.ModifyEditFieldLabel.Text = 'Modify?';
            app.Condi = uieditfield(app.Panel, 'numeric');
            app.Condi.Limits = [0 1];
            app.CsatEditFieldLabel = uilabel(app.Panel);
            app.CsatEditFieldLabel.Text = 'Csat';
            app.Csat = uieditfield(app.Panel, 'numeric');
            app.Csat.Limits = [0 Inf];
            app.Csat.Value = 892;
            app.Panel2 = uipanel(app.ViewTab);
            app.Panel2.Title = 'Panel2';
            app.LxEditFieldLabel = uilabel(app.Panel2);
            app.LxEditFieldLabel.Text = 'Lx';
            app.Lx = uieditfield(app.Panel2, 'numeric');
            app.Lx.Limits = [0 Inf];
            app.WEditFieldLabel = uilabel(app.Panel2);
            app.WEditFieldLabel.Text = 'W';
            app.W = uieditfield(app.Panel2, 'numeric');
            app.W.Limits = [0 Inf];
            app.UyEditFieldLabel = uilabel(app.Panel2);
            app.UyEditFieldLabel.Text = 'Uy';
            app.Uy = uieditfield(app.Panel2, 'numeric');
            app.Uy.Limits = [0 Inf];
            app.HEditFieldLabel = uilabel(app.Panel2);
            app.HEditFieldLabel.Text = 'H';
            app.H = uieditfield(app.Panel2, 'numeric');
            app.H.Limits = [0 Inf];
            app.LModifyEditFieldLabel = uilabel(app.Panel2);
            app.LModifyEditFieldLabel.Text = 'L Modify?';
            app.LModify = uieditfield(app.Panel2, 'numeric');
            app.FittingData = uitable(app.VuimaMain);
            app.FittingData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.FittingData.RowName = {};
            app.FittingData.ColumnEditable = true;
            app.Status = uieditfield(app.VuimaMain, 'text');
            app.FittingPanel = uipanel(app.VuimaMain);
            app.FittingPanel.SizeChangedFcn = createCallbackFcn(app, @FittingPanelSizeChanged, true);
            app.fittingY = uiaxes(app.FittingPanel);
            app.fittingX = uiaxes(app.FittingPanel);
            app.FittingSettingPanel = uipanel(app.FittingPanel);
            app.Auto = uicheckbox(app.FittingSettingPanel);
            app.Auto.Text = 'Auto';
            app.FitButton = uibutton(app.FittingSettingPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.FittingModeDropDown = uidropdown(app.FittingSettingPanel);
            app.FittingModeDropDown.Items = {'Gaussian', 'DG-Concentric', 'Parabola', 'ParabolaGaussian', 'DG', 'Customed'};
            app.FittingModeDropDown.Editable = 'on';
            app.FittingModeDropDown.Value = 'Gaussian';
            app.FittingSettingButton = uibutton(app.FittingSettingPanel, 'push');
            app.FittingSettingButton.ButtonPushedFcn = createCallbackFcn(app, @FittingSettingButtonPushed, true);
            app.FittingSettingButton.Text = 'Setting';
            app.StopButton = uibutton(app.FittingSettingPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.pksSpinnerLabel = uilabel(app.FittingSettingPanel);
            app.pksSpinnerLabel.Text = 'pks';
            app.pks = uispinner(app.FittingSettingPanel);
            app.pks.Limits = [1 Inf];
            app.pks.Value = 1;
            app.ImageShow = uiaxes(app.FittingPanel);
            app.Light = uieditfield(app.VuimaMain, 'numeric');
            app.Light.Editable = 'off';
            app.QuickFitting = uipanel(app.VuimaMain);
            app.QuickFitting.Title = 'Panel3';
            app.peakEditFieldLabel = uilabel(app.QuickFitting);
            app.peakEditFieldLabel.Text = 'peak';
            app.peak = uieditfield(app.QuickFitting, 'numeric');
            app.xcEditFieldLabel = uilabel(app.QuickFitting);
            app.xcEditFieldLabel.Text = 'xc';
            app.xc = uieditfield(app.QuickFitting, 'numeric');
            app.ycEditFieldLabel = uilabel(app.QuickFitting);
            app.ycEditFieldLabel.Text = 'yc';
            app.yc = uieditfield(app.QuickFitting, 'numeric');
            app.sigxEditFieldLabel = uilabel(app.QuickFitting);
            app.sigxEditFieldLabel.Text = 'sigx';
            app.sigx = uieditfield(app.QuickFitting, 'numeric');
            app.sigyEditFieldLabel = uilabel(app.QuickFitting);
            app.sigyEditFieldLabel.Text = 'sigy';
            app.sigy = uieditfield(app.QuickFitting, 'numeric');
            app.peak_2 = uieditfield(app.QuickFitting, 'numeric');
            app.xc_2 = uieditfield(app.QuickFitting, 'numeric');
            app.yc_2 = uieditfield(app.QuickFitting, 'numeric');
            app.sigx_2 = uieditfield(app.QuickFitting, 'numeric');
            app.sigy_2 = uieditfield(app.QuickFitting, 'numeric');
            app.AutoIniFit = uicheckbox(app.QuickFitting);
            app.AutoIniFit.Text = 'Auto';
            app.ErrorData = uitable(app.VuimaMain);
            app.ErrorData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ErrorData.RowName = {};
            app.ErrorData.ColumnEditable = true;
            app.CountRate = uieditfield(app.VuimaMain, 'numeric');
            app.CountRate.Editable = 'off';
            app.odValue = uieditfield(app.VuimaMain, 'numeric');
            app.odValue.ValueChangedFcn = createCallbackFcn(app, @odValueValueChanged, true);
            app.odValue.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Vuima()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VuimaMain)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VuimaMain)
        end
    end
end